classdef ScoreApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        MainPageTab                matlab.ui.container.Tab
        Image_3                    matlab.ui.control.Image
        Image_2                    matlab.ui.control.Image
        Image                      matlab.ui.control.Image
        InstructionsLabel          matlab.ui.control.Label
        TabGroup2                  matlab.ui.container.TabGroup
        NovelTab                   matlab.ui.container.Tab
        LholdFarwalltimeLabel      matlab.ui.control.Label
        ZholdNoveltimeLabel        matlab.ui.control.Label
        ControlsLabel              matlab.ui.control.Label
        clickPrintCSVandfollowpromptsLabel  matlab.ui.control.Label
        MAKESURETHATSUBMITISUNSELECTEDscorerepeatLabel  matlab.ui.control.Label
        TypedateandanimalclickSubmitclickBegintrialLabel  matlab.ui.control.Label
        FlowLabel                  matlab.ui.control.Label
        JSETab_2                   matlab.ui.container.Tab
        ZholdSocialtimeLabel       matlab.ui.control.Label
        ControlsLabel_2            matlab.ui.control.Label
        clickPrintCSVandfollowpromptsLabel_2  matlab.ui.control.Label
        MAKESURETHATSUBMITISUNSELECTEDscorerepeatLabel_2  matlab.ui.control.Label
        TypedateandanimalclickSubmitclickBegintrialLabel_2  matlab.ui.control.Label
        FlowLabel_2                matlab.ui.control.Label
        EffortTab                  matlab.ui.container.Tab
        NALabel                    matlab.ui.control.Label
        ControlsLabel_3            matlab.ui.control.Label
        clickPrintCSVandfollowpromptsLabel_3  matlab.ui.control.Label
        nextpaneclickSubmitinpanewithtrialinforepeatLabel  matlab.ui.control.Label
        TypedateandanimalclickSubmittyperelevanttrialinfoinLabel  matlab.ui.control.Label
        FlowLabel_3                matlab.ui.control.Label
        SocialTab                  matlab.ui.container.Tab
        ZpressIndividualpushLabel  matlab.ui.control.Label
        ControlsLabel_4            matlab.ui.control.Label
        clickPrintCSVandfollowpromptsLabel_4  matlab.ui.control.Label
        MAKESURETHATSUBMITISUNSELECTEDscorerepeatLabel_3  matlab.ui.control.Label
        TypedaytrialcohortanclickSubmitclickBegintrialLabel  matlab.ui.control.Label
        FlowLabel_4                matlab.ui.control.Label
        CSVsTab                    matlab.ui.container.Tab
        csvformatForexampleexamplecsvLabel  matlab.ui.control.Label
        ForthefilenamepromptensurethatyouhavenospacesandthatisinLabel  matlab.ui.control.Label
        copyaspathoptionsinthedropdownmenuLabel  matlab.ui.control.Label
        TogetthefilepathrightclickonfolderandselectthecopypathorLabel  matlab.ui.control.Label
        Image3                     matlab.ui.control.Image
        Image2                     matlab.ui.control.Image
        WelcometotheBarattaLabScoringModuleLabel  matlab.ui.control.Label
        NovelObjectTab             matlab.ui.container.Tab
        PrintCSVButton_4           matlab.ui.control.Button
        CurrentTrialPanel          matlab.ui.container.Panel
        AbortButton                matlab.ui.control.Button
        Lamp_2                     matlab.ui.control.Lamp
        Lamp                       matlab.ui.control.Lamp
        NULLLabel_7                matlab.ui.control.Label
        NULLLabel_6                matlab.ui.control.Label
        NULLLabel_5                matlab.ui.control.Label
        RecordingLamp              matlab.ui.control.Lamp
        RecordingLampLabel         matlab.ui.control.Label
        TimeElapsedGauge_3         matlab.ui.control.LinearGauge
        TimeElapsedGauge_3Label    matlab.ui.control.Label
        AnimalLabel_2              matlab.ui.control.Label
        BegintrialButton           matlab.ui.control.Button
        FarwalltimeLabel           matlab.ui.control.Label
        NoveltimeLabel             matlab.ui.control.Label
        UITable                    matlab.ui.control.Table
        TrialInformationPanel      matlab.ui.container.Panel
        SubmitButton_12            matlab.ui.control.Button
        AnimalTextArea_5           matlab.ui.control.TextArea
        AnimalTextArea_5Label      matlab.ui.control.Label
        DateTextArea_4             matlab.ui.control.TextArea
        DateTextArea_4Label        matlab.ui.control.Label
        JSETab                     matlab.ui.container.Tab
        PrintCSVButton_3           matlab.ui.control.Button
        UITable_3                  matlab.ui.control.Table
        CurrentTrialPanel_3        matlab.ui.container.Panel
        AbortButton_2              matlab.ui.control.Button
        Lamp_3                     matlab.ui.control.Lamp
        NULLLabel_10               matlab.ui.control.Label
        ExplorationLabel           matlab.ui.control.Label
        TimeElapsedGauge           matlab.ui.control.LinearGauge
        TimeElapsedGaugeLabel      matlab.ui.control.Label
        BegintrialButton_3         matlab.ui.control.Button
        RecordingLamp_3            matlab.ui.control.Lamp
        RecordingLamp_3Label       matlab.ui.control.Label
        NULLLabel_9                matlab.ui.control.Label
        AnimalLabel_6              matlab.ui.control.Label
        TrialInformationPanel_3    matlab.ui.container.Panel
        SubmitButton_13            matlab.ui.control.Button
        AnimalTextArea_6           matlab.ui.control.TextArea
        AnimalTextArea_6Label      matlab.ui.control.Label
        DateTextArea_5             matlab.ui.control.TextArea
        DateTextArea_5Label        matlab.ui.control.Label
        EffortTubeTab              matlab.ui.container.Tab
        PrintCSVButton_5           matlab.ui.control.Button
        TrialInformationPanel_5    matlab.ui.container.Panel
        SubmitButton_10            matlab.ui.control.Button
        AnimalTextArea_4           matlab.ui.control.TextArea
        AnimalTextArea_4Label      matlab.ui.control.Label
        DateTextArea_3             matlab.ui.control.TextArea
        DateTextArea_3Label        matlab.ui.control.Label
        UITable_4                  matlab.ui.control.Table
        TimesPanel                 matlab.ui.container.Panel
        SubmitButton_11            matlab.ui.control.Button
        gEditField                 matlab.ui.control.NumericEditField
        gLabel                     matlab.ui.control.Label
        WeightorTrialLabel         matlab.ui.control.Label
        sEditField_6               matlab.ui.control.NumericEditField
        sEditField_6Label          matlab.ui.control.Label
        mEditField_6               matlab.ui.control.NumericEditField
        mEditField_6Label          matlab.ui.control.Label
        sEditField_5               matlab.ui.control.NumericEditField
        sEditField_5Label          matlab.ui.control.Label
        mEditField_5               matlab.ui.control.NumericEditField
        mEditField_5Label          matlab.ui.control.Label
        sEditField_4               matlab.ui.control.NumericEditField
        sEditField_4Label          matlab.ui.control.Label
        mEditField_4               matlab.ui.control.NumericEditField
        mEditField_4Label          matlab.ui.control.Label
        GatePullLabel              matlab.ui.control.Label
        BottleDropLabel            matlab.ui.control.Label
        BottleTouchLabel           matlab.ui.control.Label
        NULLLabel_11               matlab.ui.control.Label
        AnimalLabel_7              matlab.ui.control.Label
        SocialTubeTab              matlab.ui.container.Tab
        PrintCSVButton_6           matlab.ui.control.Button
        UITable_2                  matlab.ui.control.Table
        CurrentTrialPanel_2        matlab.ui.container.Panel
        AbortButton_3              matlab.ui.control.Button
        BegintrialButton_4         matlab.ui.control.Button
        TimeElapsedGauge_2         matlab.ui.control.LinearGauge
        TimeElapsedGauge_2Label    matlab.ui.control.Label
        RecordingLamp_2            matlab.ui.control.Lamp
        RecordingLamp_2Label       matlab.ui.control.Label
        NULLLabel_4                matlab.ui.control.Label
        NULLLabel_3                matlab.ui.control.Label
        NULLLabel_2                matlab.ui.control.Label
        NULLLabel                  matlab.ui.control.Label
        PushesLabel                matlab.ui.control.Label
        TrialLabel_2               matlab.ui.control.Label
        AnimalLabel_5              matlab.ui.control.Label
        CohortLabel                matlab.ui.control.Label
        TrialInformationPanel_2    matlab.ui.container.Panel
        SubmitButton_14            matlab.ui.control.Button
        AnimalTextArea_7           matlab.ui.control.TextArea
        AnimalTextArea_7Label      matlab.ui.control.Label
        CohortTextArea             matlab.ui.control.TextArea
        CohortTextAreaLabel        matlab.ui.control.Label
        TrialTextArea              matlab.ui.control.TextArea
        TrialTextAreaLabel         matlab.ui.control.Label
        DayTextArea                matlab.ui.control.TextArea
        DayTextAreaLabel           matlab.ui.control.Label
        SettingsTab                matlab.ui.container.Tab
        sEditField_8               matlab.ui.control.NumericEditField
        sEditField_8Label          matlab.ui.control.Label
        sEditField_9               matlab.ui.control.NumericEditField
        sEditField_9Label          matlab.ui.control.Label
        sEditField_7               matlab.ui.control.NumericEditField
        sEditField_7Label          matlab.ui.control.Label
        SubmitButton_17            matlab.ui.control.Button
        SocialTubeTrialTimeLabel   matlab.ui.control.Label
        SubmitButton_16            matlab.ui.control.Button
        JSETrialTimeLabel          matlab.ui.control.Label
        SubmitButton_15            matlab.ui.control.Button
        NovelObjectTrialTimeLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        % Novel object data
            novelDate = "";
            novelAnimal = "";
        
            novelTime = 0.0;
            realNovel = 0.0;
            farWallTime = 0.0;
            realWall = 0.0;
            
            scoreGateNovel = false;
            zinit = 0.0;
            zinit2 = 0.0;
            zgate = false;
            duct = true;
            tape = true;
            linit = 0.0;
            linit2 = 0.0;
            lgate = false;
        
            novelCSV = ["Date","Animal #", "Novel Exploration (s)", "Far Wall (s)"];
            regulaTable;

            novelPossible = 0;
            novelTrialTime = 300;
            
            novelAbort = false;
        % Effort Tube properties
            effortAnimal = "";
            effortDate = "";
            weight = "";
            
            gatePullMin = 0;
            gatePullSec = 0;
            
            bottleMin = 0;
            bottleSec = 0;
            
            dropMin = 0;
            dropSec = 0;
    
            gateTotal = 0;
            bottleTotal = 0;
            dropTotal = 0;
    
            bottleLatency = 0;
            pushTime = 0;
    
            effortTable;
            effortCSV = ["Date","Animal #", "Weight or Trial #", "Bottle Latency", "Push Time"];
        
        % JSE properties
            JSEdate = "";
            JSEanimal = "";
            JSEgate = false;
            socialTime = 0.0;
            realSocial = 0.0;
            wd40 = true;

            JSEinit = 0.0;
            JSEinit2 = 0.0;

            JSEtable;
            JSEcsv = ["Date", "Animal #", "Exploration (s)"];

            JSETrialTime = 300;
            JSEPossible = 0;
            
            JSEabort = false;
        % Social tube properites
            tubeDay = "";
            tubeTrial = "";
            tubeCohort = "";
            tubeAnimal = "";
            pushes = 0;

            tubeTable;
            tubeCSV = ["Day", "Cohort", "Animal #", "Trial #", "Pushes"];

            animol = false;
            chinken = false;

            socialTrialTime = 120;
            socialPossible = 0;
            socialAbort = false;
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubmitButton_11
        function SubmitButton_11Pushed(app, event)
           % convert minutes to seconds and add to get numbers
           app.gateTotal = (app.gatePullMin*60) + app.gatePullSec;
           app.bottleTotal = (app.bottleMin * 60) + app.bottleSec;
           app.dropTotal = (app.dropMin * 60) + app.dropSec;
           app.bottleLatency = app.bottleTotal - app.gateTotal;
           app.pushTime = app.dropTotal - app.bottleTotal;
           newTable = [app.effortDate,app.effortAnimal,app.weight,app.pushTime];
           app.effortTable = [newTable;app.effortTable];
           app.UITable_4.Data = app.effortTable;
          
           newTable2 = [app.effortDate,app.effortAnimal,app.weight,app.bottleLatency,app.pushTime];
           app.effortCSV = [app.effortCSV;newTable2];
        end

        % Value changed function: DateTextArea_3
        function DateTextArea_3ValueChanged(app, event)
            % Effort date 
            value = app.DateTextArea_3.Value;
            app.effortDate = value;
        end

        % Value changed function: AnimalTextArea_4
        function AnimalTextArea_4ValueChanged(app, event)
            % Effort animal
            value = app.AnimalTextArea_4.Value;
            app.effortAnimal = value;
        end

        % Button pushed function: SubmitButton_10
        function SubmitButton_10Pushed(app, event)
            app.NULLLabel_11.Text = app.effortAnimal;
        end

        % Value changed function: mEditField_4
        function mEditField_4ValueChanged(app, event)
            % Gate Pull min
            value = app.mEditField_4.Value;
            app.gatePullMin = value;
        end

        % Value changed function: sEditField_4
        function sEditField_4ValueChanged(app, event)
            % Gate pull sec
            value = app.sEditField_4.Value;
            app.gatePullSec = value;
        end

        % Value changed function: mEditField_5
        function mEditField_5ValueChanged(app, event)
            % bottle min
            value = app.mEditField_5.Value;
            app.bottleMin = value;
        end

        % Value changed function: sEditField_5
        function sEditField_5ValueChanged(app, event)
            % bottle sec
            value = app.sEditField_5.Value;
            app.bottleSec = value;
        end

        % Value changed function: mEditField_6
        function mEditField_6ValueChanged(app, event)
            % drop min
            value = app.mEditField_6.Value;
            app.dropMin = value;
        end

        % Value changed function: sEditField_6
        function sEditField_6ValueChanged(app, event)
            % drop sec
            value = app.sEditField_6.Value;
            app.dropSec = value;
        end

        % Button pushed function: PrintCSVButton_5
        function PrintCSVButton_5Pushed(app, event)
            % Setting up file path
            prompt = {'Input your filename:',"Input your filepath:"};
            dlg = {'filename','folderpath'};
            stuff = inputdlg(prompt,'CSV Printer',[1 50],dlg);
            
            % Exit if there's issues regarding user input or early exit
            if(size(stuff) <=1)
               msgbox("Please enter in values","Error","error"); 
               return;
            end
            
            % Conversion to string for input validation
            filename = string(stuff(1));
            filepath = string(stuff(2));

            % Flag for function exit
            boolflag = false;
            
            % Checking filename will work properly
            isvalid = endsWith(filename,'.csv');
            numPeriods = count(filename,'.');
            if(~isvalid || (numPeriods > 1) || (filename == ".csv"))
                msgbox(filename + " is not a valid filename.","Error","error");
                boolflag = true;
            end

            % Checking if filepath is proper
            filepath = erase(filepath,'"');
            if(~isfolder(filepath))
                msgbox(filepath + " is not a valid filepath.","Error","error");
                boolflag = true;
            end
            
            % If either of these checks have failed, exit function
            if boolflag
                return;
            end
            
            if(isfile(filepath + "\" + filename))
                errordlg('ERROR: A file with that name already exists.', 'File Error');
                return;
            end

            writematrix(app.effortCSV,filepath + "\" + filename);

            if(isfile(filepath + "\" + filename))
                msgbox('File saved successfully', 'Save Success');
            else
                errordlg('Some error saving file, try again.', 'File Error');
            end
        end

        % Value changed function: gEditField
        function gEditFieldValueChanged(app, event)
            % weight
            value = app.gEditField.Value;
            app.weight = value;
        end

        % Value changed function: DateTextArea_4
        function DateTextAreaValueChanged(app, event)
            % Novel date
            value = app.DateTextArea_4.Value;
            app.novelDate = value;
        end

        % Value changed function: AnimalTextArea_5
        function AnimalTextAreaValueChanged(app, event)
            % Novel animal
            value = app.AnimalTextArea_5.Value;
            app.novelAnimal = value;
        end

        % Button pushed function: SubmitButton_12
        function SubmitButtonPushed(app, event)
            app.NULLLabel_5.Text = app.novelAnimal;
        end

        % Button pushed function: BegintrialButton
        function BegintrialButtonPushed(app, event)
            app.novelTime = 0.0;
            app.realNovel = 0.0;

            app.farWallTime = 0.0;
            app.realWall = 0.0;

            app.zinit2 = tic;
            toc(app.zinit2);
            app.zinit2 = 0.0;
            app.duct = true;
            
            app.linit2 = tic;
            toc(app.linit2);
            app.linit2 = 0.0;
            app.tape = true;

            for i = 1:4
                app.RecordingLamp.Color = [1 1 0];
                pause(0.5);
                app.RecordingLamp.Color = [0 0 0];
                pause(0.5);
            end

            app.RecordingLamp.Color = [1 0 0];
            app.scoreGateNovel = true;

            app.AbortButton.Visible = "on";
            app.BegintrialButton.Visible = "off";
            
            for i = 1:app.novelTrialTime
                pause(1);
                app.TimeElapsedGauge_3.Value = app.TimeElapsedGauge_3.Value + (1/60);
                if app.novelAbort
                    app.novelAbort = false;
                    app.AbortButton.Visible = "off";
                    app.BegintrialButton.Visible = "on";
                    
                    app.zinit2 = tic;
                    toc(app.zinit2);
                    app.zinit2 = 0.0;
                    app.duct = true;
                    
                    app.linit2 = tic;
                    toc(app.linit2);
                    app.linit2 = 0.0;
                    app.tape = true;
        
                    app.novelTime= 0.0;
                    app.realNovel = 0.0;

                    app.farWallTime = 0.0;
                    app.realWall = 0.0;
                    
                    app.RecordingLamp.Color = [0 0 0];
                    app.Lamp.Color = [0 0 0];
                    app.Lamp_2.Color = [0 0 0];
                    app.TimeElapsedGauge_3.Value = 0.0;

                    app.NULLLabel_6.Text = "NULL";
                    app.NULLLabel_5.Text = "NULL";
                    app.NULLLabel_7.Text = "NULL";
                    app.scoreGateNovel = false;
                    return;
                end
            end
            
            app.scoreGateNovel = false;
            if ~app.duct
                app.realNovel = app.realNovel + toc(app.zinit2);
            end
            
            if ~app.tape
                app.realWall = app.realWall + toc(app.linit2);
            end
            
            app.AbortButton.Visible = "off";
            app.BegintrialButton.Visible = "on";
            
            app.RecordingLamp.Color = [0 0 0];
            app.Lamp.Color = [0 0 0];
            app.Lamp_2.Color = [0 0 0];
            
            app.NULLLabel_6.Text = num2str(app.realNovel, '%0.2f');
            app.NULLLabel_7.Text = num2str(app.realWall, '%0.2f');
            
            newTable = [app.novelDate,app.novelAnimal,app.realNovel,app.realWall];
            
            app.novelCSV = [app.novelCSV;newTable];
            app.regulaTable = [newTable;app.regulaTable];
            app.UITable.Data = app.regulaTable;

            app.TimeElapsedGauge_3.Value = 0.0;

            app.novelTime = 0.0;
            app.realNovel = 0.0;

            app.farWallTime = 0.0;
            app.realWall = 0.0;

            app.NULLLabel_5.Text = "NULL";
            app.NULLLabel_6.Text = "NULL";
            app.NULLLabel_7.Text = "NULL";
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = string(event.Key);
            if(strlength(key) > 1)
                return;
            end

            switch key
                case "z"
                    if app.scoreGateNovel
                        app.zinit = tic;

                        if app.duct
                            app.zinit2 = tic;
                            app.duct = false;
                        end

                        app.Lamp_2.Color = [1 0 0];
                        pause(.025);
                        app.novelTime = app.novelTime + toc(app.zinit);
                        app.NULLLabel_6.Text = num2str(app.novelTime, '%0.2f');
                        app.zgate = true;

                    elseif app.JSEgate
                        app.JSEinit = tic;

                        if app.wd40
                            app.JSEinit2 = tic;
                            app.wd40 = false;
                        end

                        app.Lamp_3.Color = [1 0 0];
                        pause(.025);
                        app.socialTime = app.socialTime + toc(app.JSEinit);
                        app.NULLLabel_10.Text = num2str(app.socialTime, '%0.2f'); 
                        app.zgate = true;

                    elseif app.animol
                        app.chinken = true;
                    end
                    
                case "l"
                    if app.scoreGateNovel
                        app.linit = tic;

                        if app.tape
                            app.linit2 = tic;
                            app.tape = false;
                        end

                        app.Lamp.Color = [1 0 0];
                        pause(.025);
                        app.farWallTime = app.farWallTime + toc(app.linit);
                        app.NULLLabel_7.Text = num2str(app.farWallTime, '%0.2f');
                        app.lgate = true;
                    end
                otherwise
                    % Handle other keys if needed
            end
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = string(event.Key);
            
            switch key
                case "z"
                    if app.scoreGateNovel && app.zgate
                        app.Lamp_2.Color = [0 0 0];
                        app.realNovel = app.realNovel + toc(app.zinit2);
                        app.NULLLabel_6.Text = num2str(app.realNovel, '%0.2f');
                        app.novelTime = app.realNovel;
                        app.zgate = false;
                        app.duct = true;

                    elseif app.JSEgate && app.zgate
                        app.Lamp_3.Color = [0 0 0];
                        app.realSocial = app.realSocial + toc(app.JSEinit2);
                        app.NULLLabel_10.Text = num2str(app.realSocial, '%0.2f'); 
                        app.socialTime = app.realSocial;
                        app.zgate = false;
                        app.wd40 = true;

                    elseif app.animol && app.chinken
                        app.pushes = app.pushes + 1;
                        app.NULLLabel_4.Text = num2str(app.pushes);
                        app.chinken = false;
                    end

                case "l"
                    if app.scoreGateNovel && app.lgate
                        app.Lamp.Color = [0 0 0]; 
                        app.realWall = app.realWall + toc(app.linit2);
                        app.NULLLabel_7.Text = num2str(app.realWall, '%0.2f');
                        app.farWallTime = app.realWall;
                        app.lgate = false;
                        app.tape = true;
                    end

                otherwise
                    % Handle other keys if needed
            end
        end

        % Button pushed function: PrintCSVButton_4
        function PrintCSVButton_4Pushed(app, event)
            % Setting up file path
            prompt = {'Input your filename:',"Input your filepath:"};
            dlg = {'filename','folderpath'};
            stuff = inputdlg(prompt,'CSV Printer',[1 50],dlg);
            
            % Exit if there's issues regarding user input or early exit
            if(size(stuff) <=1)
               msgbox("Please enter in values","Error","error"); 
               return;
            end
            
            % Conversion to string for input validation
            filename = string(stuff(1));
            filepath = string(stuff(2));

            % Flag for function exit
            boolflag = false;
            
            % Checking filename will work properly
            isvalid = endsWith(filename,'.csv');
            numPeriods = count(filename,'.');
            if(~isvalid || (numPeriods > 1) || (filename == ".csv"))
                msgbox(filename + " is not a valid filename.","Error","error");
                boolflag = true;
            end

            % Checking if filepath is proper
            filepath = erase(filepath,'"');
            if(~isfolder(filepath))
                msgbox(filepath + " is not a valid filepath.","Error","error");
                boolflag = true;
            end
            
            % If either of these checks have failed, exit function
            if boolflag
                return;
            end
            
            if(isfile(filepath + "\" + filename))
                errordlg('ERROR: A file with that name already exists.', 'File Error');
                return;
            end

            writematrix(app.novelCSV,filepath + "\" + filename);

            if(isfile(filepath + "\" + filename))
                msgbox('File saved successfully', 'Save Success');
            else
                errordlg('Some error saving file, try again.', 'File Error');
            end
        end

        % Value changed function: DateTextArea_5
        function DateTextArea_5ValueChanged(app, event)
            % JSE date
            value = app.DateTextArea_5.Value;
            app.JSEdate = value;
        end

        % Value changed function: AnimalTextArea_6
        function AnimalTextArea_6ValueChanged(app, event)
            % JSE animal
            value = app.AnimalTextArea_6.Value;
            app.JSEanimal = value;
        end

        % Button pushed function: SubmitButton_13
        function SubmitButton_13Pushed(app, event)
            app.NULLLabel_9.Text = app.JSEanimal;
        end

        % Button pushed function: BegintrialButton_3
        function BegintrialButton_3Pushed(app, event)
            app.zinit2 = tic;
            toc(app.zinit2);
            app.zinit2 = 0.0;
            app.duct = true;
            
            app.linit2 = tic;
            toc(app.linit2);
            app.linit2 = 0.0;
            app.tape = true;

            app.socialTime = 0.0;
            app.realSocial = 0.0;
            
            app.RecordingLamp_3.Color = [0 0 0];
            app.JSEgate = false;
            app.Lamp_3.Color = [0 0 0];
            app.TimeElapsedGauge.Value = 0.0;
            
            for i = 1:4
                app.RecordingLamp_3.Color = [1 1 0];
                pause(0.5);
                app.RecordingLamp_3.Color = [0 0 0];
                pause(0.5);
            end
           
            app.RecordingLamp_3.Color= [1 0 0];
            app.JSEgate = true;
            
            app.AbortButton_2.Visible = "on";
            app.BegintrialButton_3.Visible = "off";
            for i = 1:app.JSETrialTime
                pause(1);
                app.TimeElapsedGauge.Value = app.TimeElapsedGauge.Value + (1/60);
                if app.JSEabort
                    app.JSEabort = false;
                    app.AbortButton_2.Visible = "off";
                    app.BegintrialButton_3.Visible = "on";
                    
                    app.zinit2 = tic;
                    toc(app.zinit2);
                    app.zinit2 = 0.0;
                    app.duct = true;
                    
                    app.linit2 = tic;
                    toc(app.linit2);
                    app.linit2 = 0.0;
                    app.tape = true;
        
                    app.socialTime = 0.0;
                    app.realSocial = 0.0;
                    
                    app.RecordingLamp_3.Color = [0 0 0];
                    app.JSEgate = false;
                    app.Lamp_3.Color = [0 0 0];
                    app.TimeElapsedGauge.Value = 0.0;
        
                    app.NULLLabel_9.Text = "NULL";
                    app.NULLLabel_10.Text = "NULL";
                    return;
                end
            end
            
            app.RecordingLamp_3.Color = [0 0 0];
            app.JSEgate = false;
            app.Lamp_3.Color = [0 0 0];
            app.TimeElapsedGauge.Value = 0.0;
            
            app.AbortButton_2.Visible = "off";
            app.BegintrialButton_3.Visible = "on";
                    
            app.NULLLabel_10.Text = num2str(app.socialTime, '%0.2f');

            tempTable = [app.JSEdate,app.JSEanimal,app.socialTime];
            app.JSEtable = [tempTable;app.JSEtable];
            app.JSEcsv = [app.JSEcsv;tempTable];
            app.UITable_3.Data = app.JSEtable;

            app.socialTime = 0.0;
            app.NULLLabel_9.Text = "NULL";
            app.NULLLabel_10.Text = "NULL";
        end

        % Button pushed function: PrintCSVButton_3
        function PrintCSVButton_3Pushed(app, event)
            % Setting up file path
            prompt = {'Input your filename:',"Input your filepath:"};
            dlg = {'filename','folderpath'};
            stuff = inputdlg(prompt,'CSV Printer',[1 50],dlg);
            
            % Exit if there's issues regarding user input or early exit
            if(size(stuff) <=1)
               msgbox("Please enter in values","Error","error"); 
               return;
            end
            
            % Conversion to string for input validation
            filename = string(stuff(1));
            filepath = string(stuff(2));

            % Flag for function exit
            boolflag = false;
            
            % Checking filename will work properly
            isvalid = endsWith(filename,'.csv');
            numPeriods = count(filename,'.');
            if(~isvalid || (numPeriods > 1) || (filename == ".csv"))
                msgbox(filename + " is not a valid filename.","Error","error");
                boolflag = true;
            end

            % Checking if filepath is proper
            filepath = erase(filepath,'"');
            if(~isfolder(filepath))
                msgbox(filepath + " is not a valid filepath.","Error","error");
                boolflag = true;
            end
            
            % If either of these checks have failed, exit function
            if boolflag
                return;
            end
            
            if(isfile(filepath + "\" + filename))
                errordlg('ERROR: A file with that name already exists.', 'File Error');
                return;
            end

            writematrix(app.JSEcsv,filepath + "\" + filename);

            if(isfile(filepath + "\" + filename))
                msgbox('File saved successfully', 'Save Success');
            else
                errordlg('Some error saving file, try again.', 'File Error');
            end
        end

        % Button pushed function: SubmitButton_14
        function SubmitButton_14Pushed(app, event)
            app.NULLLabel.Text = app.tubeCohort;
            app.NULLLabel_2.Text = app.tubeAnimal;
            app.NULLLabel_3.Text = app.tubeTrial;
            app.NULLLabel_4.Text = "0";
        end

        % Value changed function: DayTextArea
        function DayTextAreaValueChanged(app, event)
            value = app.DayTextArea.Value;
            app.tubeDay = value;
        end

        % Value changed function: TrialTextArea
        function TrialTextAreaValueChanged(app, event)
            value = app.TrialTextArea.Value;
            app.tubeTrial = value;
        end

        % Value changed function: CohortTextArea
        function CohortTextAreaValueChanged(app, event)
            value = app.CohortTextArea.Value;
            app.tubeCohort = value;
        end

        % Value changed function: AnimalTextArea_7
        function AnimalTextArea_7ValueChanged(app, event)
            value = app.AnimalTextArea_7.Value;
            app.tubeAnimal = value;
        end

        % Button pushed function: PrintCSVButton_6
        function PrintCSVButton_6Pushed(app, event)
            % Setting up file path
            prompt = {'Input your filename:',"Input your filepath:"};
            dlg = {'filename','folderpath'};
            stuff = inputdlg(prompt,'CSV Printer',[1 50],dlg);
            
            % Exit if there's issues regarding user input or early exit
            if(size(stuff) <=1)
               msgbox("Please enter in values","Error","error"); 
               return;
            end
            
            % Conversion to string for input validation
            filename = string(stuff(1));
            filepath = string(stuff(2));

            % Flag for function exit
            boolflag = false;
            
            % Checking filename will work properly
            isvalid = endsWith(filename,'.csv');
            numPeriods = count(filename,'.');
            if(~isvalid || (numPeriods > 1) || (filename == ".csv"))
                msgbox(filename + " is not a valid filename.","Error","error");
                boolflag = true;
            end

            % Checking if filepath is proper
            filepath = erase(filepath,'"');
            if(~isfolder(filepath))
                msgbox(filepath + " is not a valid filepath.","Error","error");
                boolflag = true;
            end
            
            % If either of these checks have failed, exit function
            if boolflag
                return;
            end
            
            if(isfile(filepath + "\" + filename))
                errordlg('ERROR: A file with that name already exists.', 'File Error');
                return;
            end

            writematrix(app.tubeCSV,filepath + "\" + filename);

            if(isfile(filepath + "\" + filename))
                msgbox('File saved successfully', 'Save Success');
            else
                errordlg('Some error saving file, try again.', 'File Error');
            end
        end

        % Button pushed function: BegintrialButton_4
        function BegintrialButton_4Pushed(app, event)
            for i = 1:4
                app.RecordingLamp_2.Color = [1 1 0];
                pause(0.5);
                app.RecordingLamp_2.Color = [0 0 0];
                pause(0.5);
            end

            app.RecordingLamp_2.Color = [1 0 0];
            app.animol = true;
            app.chinken = false;

            app.RecordingLamp_2.Color = [1 0 0];
            app.AbortButton_3.Visible = "on";
            app.BegintrialButton_4.Visible = "off";
            
            for i = 1:app.socialTrialTime
                pause(1);
                app.TimeElapsedGauge_2.Value = app.TimeElapsedGauge_2.Value + (1/60);
                if app.socialAbort
                    app.socialAbort = false;
                    app.AbortButton_3.Visible = "off";
                    app.BegintrialButton_4.Visible = "on";
                    
                    app.RecordingLamp_2.Color = [0 0 0];
                    app.animol = false;
                    app.TimeElapsedGauge_2.Value = 0.0;
        
                    app.NULLLabel.Text = "NULL";
                    app.NULLLabel_2.Text = "NULL";
                    app.NULLLabel_3.Text = "NULL";
                    app.NULLLabel_4.Text = "NULL";
                    app.pushes = 0.0;
                    return;
                end
            end
            
            app.AbortButton_3.Visible = "off";
            app.BegintrialButton_4.Visible = "on";

            app.RecordingLamp_2.Color = [0 0 0];
            app.TimeElapsedGauge_2.Value = 0.0;
            app.animol = false;
            
            tempTable = [app.tubeDay,app.tubeCohort,app.tubeAnimal,app.tubeTrial];
            tempTable2 = [app.tubeDay,app.tubeCohort,app.tubeAnimal,app.tubeTrial,app.pushes];
            app.tubeTable = [tempTable;app.tubeTable];
            app.UITable_2.Data = app.tubeTable;
            app.tubeCSV = [app.tubeCSV;tempTable2];

            app.NULLLabel.Text = "NULL";
            app.NULLLabel_2.Text = "NULL";
            app.NULLLabel_3.Text = "NULL";
            app.NULLLabel_4.Text = "NULL";
            app.pushes = 0.0;
        end

        % Button pushed function: SubmitButton_15
        function SubmitButton_15Pushed(app, event)
            app.novelTrialTime = app.novelPossible;
            if mod(app.novelTrialTime, 60) ~= 0 || app.novelTrialTime == 0
                errordlg('Changes must be made in intervals of 60', 'Error');
                return;
            end
            app.novelTrialTime = app.novelTrialTime/60;
            app.TimeElapsedGauge_3.Limits = [0 app.novelTrialTime];
            homeSlice = 0:app.novelTrialTime;
            app.TimeElapsedGauge_3.MajorTicks = homeSlice;
    
            str_array = cell(size(homeSlice));   % initialize the string array with the same size as the numeric array
            for i = 1:numel(homeSlice)
                str_array{i} = num2str(homeSlice(i));   % convert each cell to a string and store in the string array
            end

            app.TimeElapsedGauge_3.MajorTickLabels = str_array;
            app.novelTrialTime = app.novelTrialTime*60;
        end

        % Value changed function: sEditField_7
        function sEditField_7ValueChanged2(app, event)
            value = app.sEditField_7.Value;
            app.novelPossible = value;
        end

        % Value changed function: sEditField_9
        function sEditField_9ValueChanged(app, event)
            value = app.sEditField_9.Value;
            app.JSEPossible = value;
        end

        % Button pushed function: SubmitButton_16
        function SubmitButton_16Pushed(app, event)
            app.JSETrialTime = app.JSEPossible;
            if mod(app.JSETrialTime, 60) ~= 0 || app.JSETrialTime == 0
                errordlg('Changes must be made in intervals of 60', 'Error');
                return;
            end
            app.JSETrialTime = app.JSETrialTime/60;
            app.TimeElapsedGauge.Limits = [0 app.JSETrialTime];
            homeSlice = 0:app.JSETrialTime;
            app.TimeElapsedGauge.MajorTicks = homeSlice;
    
            str_array = cell(size(homeSlice));   % initialize the string array with the same size as the numeric array
            for i = 1:numel(homeSlice)
                str_array{i} = num2str(homeSlice(i));   % convert each cell to a string and store in the string array
            end

            app.TimeElapsedGauge.MajorTickLabels = str_array;
            app.JSETrialTime = app.JSETrialTime*60;
        end

        % Value changed function: sEditField_8
        function sEditField_8ValueChanged(app, event)
            value = app.sEditField_8.Value;
            app.socialPossible = value;
        end

        % Button pushed function: SubmitButton_17
        function SubmitButton_17Pushed(app, event)
            app.socialTrialTime = app.socialPossible;

            if mod(app.socialTrialTime, 60) ~= 0 || app.socialTrialTime == 0
                errordlg('Changes must be made in intervals of 60', 'Error');
                return;
            end
            app.socialTrialTime = app.socialTrialTime/60;
            app.TimeElapsedGauge_2.Limits = [0 app.socialTrialTime];
            homeSlice = 0:app.socialTrialTime;
            app.TimeElapsedGauge_2.MajorTicks = homeSlice;
    
            str_array = cell(size(homeSlice));   % initialize the string array with the same size as the numeric array
            for i = 1:numel(homeSlice)
                str_array{i} = num2str(homeSlice(i));   % convert each cell to a string and store in the string array
            end

            app.TimeElapsedGauge_2.MajorTickLabels = str_array;
            app.socialTrialTime = app.socialTrialTime*60;
        end

        % Button pushed function: AbortButton_2
        function AbortButton_2Pushed(app, event)
            app.JSEabort = true;
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            app.novelAbort = true;
        end

        % Button pushed function: AbortButton_3
        function AbortButton_3Pushed(app, event)
            app.socialAbort = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainPageTab = uitab(app.TabGroup);
            app.MainPageTab.Title = 'Main Page';
            app.WelcometotheBarattaLabScoringModuleLabel = uilabel(app.MainPageTab);
            app.WelcometotheBarattaLabScoringModuleLabel.Text = 'Welcome to the Baratta Lab Scoring Module!';
            app.TabGroup2 = uitabgroup(app.MainPageTab);
            app.NovelTab = uitab(app.TabGroup2);
            app.NovelTab.Title = 'Novel';
            app.FlowLabel = uilabel(app.NovelTab);
            app.FlowLabel.Text = 'Flow: ';
            app.TypedateandanimalclickSubmitclickBegintrialLabel = uilabel(app.NovelTab);
            app.TypedateandanimalclickSubmitclickBegintrialLabel.Text = 'Type date and animal # -> click "Submit" -> click "Begin trial" -> ';
            app.MAKESURETHATSUBMITISUNSELECTEDscorerepeatLabel = uilabel(app.NovelTab);
            app.MAKESURETHATSUBMITISUNSELECTEDscorerepeatLabel.Text = 'MAKE SURE THAT SUBMIT IS UNSELECTED -> score -> repeat ->';
            app.clickPrintCSVandfollowpromptsLabel = uilabel(app.NovelTab);
            app.clickPrintCSVandfollowpromptsLabel.Text = 'click "Print CSV"  and follow prompts';
            app.ControlsLabel = uilabel(app.NovelTab);
            app.ControlsLabel.Text = 'Controls:';
            app.ZholdNoveltimeLabel = uilabel(app.NovelTab);
            app.ZholdNoveltimeLabel.Text = 'Z hold = Novel time';
            app.LholdFarwalltimeLabel = uilabel(app.NovelTab);
            app.LholdFarwalltimeLabel.Text = 'L hold = Far wall time';
            app.JSETab_2 = uitab(app.TabGroup2);
            app.JSETab_2.Title = 'JSE';
            app.FlowLabel_2 = uilabel(app.JSETab_2);
            app.FlowLabel_2.Text = 'Flow: ';
            app.TypedateandanimalclickSubmitclickBegintrialLabel_2 = uilabel(app.JSETab_2);
            app.TypedateandanimalclickSubmitclickBegintrialLabel_2.Text = 'Type date and animal # -> click "Submit" -> click "Begin trial" -> ';
            app.MAKESURETHATSUBMITISUNSELECTEDscorerepeatLabel_2 = uilabel(app.JSETab_2);
            app.MAKESURETHATSUBMITISUNSELECTEDscorerepeatLabel_2.Text = 'MAKE SURE THAT SUBMIT IS UNSELECTED -> score -> repeat ->';
            app.clickPrintCSVandfollowpromptsLabel_2 = uilabel(app.JSETab_2);
            app.clickPrintCSVandfollowpromptsLabel_2.Text = 'click "Print CSV"  and follow prompts';
            app.ControlsLabel_2 = uilabel(app.JSETab_2);
            app.ControlsLabel_2.Text = 'Controls:';
            app.ZholdSocialtimeLabel = uilabel(app.JSETab_2);
            app.ZholdSocialtimeLabel.Text = 'Z hold = Social time';
            app.EffortTab = uitab(app.TabGroup2);
            app.EffortTab.Title = 'Effort';
            app.FlowLabel_3 = uilabel(app.EffortTab);
            app.FlowLabel_3.Text = 'Flow: ';
            app.TypedateandanimalclickSubmittyperelevanttrialinfoinLabel = uilabel(app.EffortTab);
            app.TypedateandanimalclickSubmittyperelevanttrialinfoinLabel.Text = 'Type date and animal # -> click "Submit" -> type relevant trial info in ';
            app.nextpaneclickSubmitinpanewithtrialinforepeatLabel = uilabel(app.EffortTab);
            app.nextpaneclickSubmitinpanewithtrialinforepeatLabel.Text = 'next pane -> click "Submit" in pane with trial info -> repeat ->';
            app.clickPrintCSVandfollowpromptsLabel_3 = uilabel(app.EffortTab);
            app.clickPrintCSVandfollowpromptsLabel_3.Text = 'click "Print CSV"  and follow prompts';
            app.ControlsLabel_3 = uilabel(app.EffortTab);
            app.ControlsLabel_3.Text = 'Controls:';
            app.NALabel = uilabel(app.EffortTab);
            app.NALabel.Text = 'N/A';
            app.SocialTab = uitab(app.TabGroup2);
            app.SocialTab.Title = 'Social';
            app.FlowLabel_4 = uilabel(app.SocialTab);
            app.FlowLabel_4.Text = 'Flow: ';
            app.TypedaytrialcohortanclickSubmitclickBegintrialLabel = uilabel(app.SocialTab);
            app.TypedaytrialcohortanclickSubmitclickBegintrialLabel.Text = 'Type day, trial #, cohort, an # -> click "Submit" -> click "Begin trial" -> ';
            app.MAKESURETHATSUBMITISUNSELECTEDscorerepeatLabel_3 = uilabel(app.SocialTab);
            app.MAKESURETHATSUBMITISUNSELECTEDscorerepeatLabel_3.Text = 'MAKE SURE THAT SUBMIT IS UNSELECTED -> score -> repeat ->';
            app.clickPrintCSVandfollowpromptsLabel_4 = uilabel(app.SocialTab);
            app.clickPrintCSVandfollowpromptsLabel_4.Text = 'click "Print CSV"  and follow prompts';
            app.ControlsLabel_4 = uilabel(app.SocialTab);
            app.ControlsLabel_4.Text = 'Controls:';
            app.ZpressIndividualpushLabel = uilabel(app.SocialTab);
            app.ZpressIndividualpushLabel.Text = 'Z press = Individual push';
            app.CSVsTab = uitab(app.TabGroup2);
            app.CSVsTab.Title = 'CSVs';
            app.Image2 = uiimage(app.CSVsTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'boi.png');
            app.Image3 = uiimage(app.CSVsTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'shid.jpg');
            app.TogetthefilepathrightclickonfolderandselectthecopypathorLabel = uilabel(app.CSVsTab);
            app.TogetthefilepathrightclickonfolderandselectthecopypathorLabel.Text = 'To get the filepath, right click on folder and select the "copy path" or';
            app.copyaspathoptionsinthedropdownmenuLabel = uilabel(app.CSVsTab);
            app.copyaspathoptionsinthedropdownmenuLabel.Text = '"copy as path" options in the drop down menu.';
            app.ForthefilenamepromptensurethatyouhavenospacesandthatisinLabel = uilabel(app.CSVsTab);
            app.ForthefilenamepromptensurethatyouhavenospacesandthatisinLabel.Text = 'For the filename prompt ensure that you have no spaces and that is in';
            app.csvformatForexampleexamplecsvLabel = uilabel(app.CSVsTab);
            app.csvformatForexampleexamplecsvLabel.Text = '".csv" format.              For example -> example.csv';
            app.InstructionsLabel = uilabel(app.MainPageTab);
            app.InstructionsLabel.Text = 'Instructions:';
            app.Image = uiimage(app.MainPageTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'rat.png');
            app.Image_2 = uiimage(app.MainPageTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'ratJump.png');
            app.Image_3 = uiimage(app.MainPageTab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'ratJump.png');
            app.NovelObjectTab = uitab(app.TabGroup);
            app.NovelObjectTab.Title = 'Novel Object';
            app.TrialInformationPanel = uipanel(app.NovelObjectTab);
            app.TrialInformationPanel.Title = 'Trial Information';
            app.DateTextArea_4Label = uilabel(app.TrialInformationPanel);
            app.DateTextArea_4Label.Text = 'Date';
            app.DateTextArea_4 = uitextarea(app.TrialInformationPanel);
            app.DateTextArea_4.ValueChangedFcn = createCallbackFcn(app, @DateTextAreaValueChanged, true);
            app.AnimalTextArea_5Label = uilabel(app.TrialInformationPanel);
            app.AnimalTextArea_5Label.Text = 'Animal #';
            app.AnimalTextArea_5 = uitextarea(app.TrialInformationPanel);
            app.AnimalTextArea_5.ValueChangedFcn = createCallbackFcn(app, @AnimalTextAreaValueChanged, true);
            app.SubmitButton_12 = uibutton(app.TrialInformationPanel, 'push');
            app.SubmitButton_12.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton_12.Text = 'Submit';
            app.UITable = uitable(app.NovelObjectTab);
            app.UITable.ColumnName = {'Date'; 'Animal #'; 'Novel Exploration (s)'; 'Far Wall (s)'};
            app.UITable.RowName = {};
            app.CurrentTrialPanel = uipanel(app.NovelObjectTab);
            app.CurrentTrialPanel.Title = 'Current Trial';
            app.NoveltimeLabel = uilabel(app.CurrentTrialPanel);
            app.NoveltimeLabel.Text = 'Novel time:';
            app.FarwalltimeLabel = uilabel(app.CurrentTrialPanel);
            app.FarwalltimeLabel.Text = 'Far wall time:';
            app.BegintrialButton = uibutton(app.CurrentTrialPanel, 'push');
            app.BegintrialButton.ButtonPushedFcn = createCallbackFcn(app, @BegintrialButtonPushed, true);
            app.BegintrialButton.Text = 'Begin trial';
            app.AnimalLabel_2 = uilabel(app.CurrentTrialPanel);
            app.AnimalLabel_2.Text = 'Animal #:';
            app.TimeElapsedGauge_3Label = uilabel(app.CurrentTrialPanel);
            app.TimeElapsedGauge_3Label.Text = 'Time Elapsed';
            app.TimeElapsedGauge_3 = uigauge(app.CurrentTrialPanel, 'linear');
            app.TimeElapsedGauge_3.Limits = [0 5];
            app.TimeElapsedGauge_3.MinorTicks = [];
            app.RecordingLampLabel = uilabel(app.CurrentTrialPanel);
            app.RecordingLampLabel.Text = 'Recording: ';
            app.RecordingLamp = uilamp(app.CurrentTrialPanel);
            app.NULLLabel_5 = uilabel(app.CurrentTrialPanel);
            app.NULLLabel_5.Text = 'NULL';
            app.NULLLabel_6 = uilabel(app.CurrentTrialPanel);
            app.NULLLabel_6.Text = 'NULL';
            app.NULLLabel_7 = uilabel(app.CurrentTrialPanel);
            app.NULLLabel_7.Text = 'NULL';
            app.Lamp = uilamp(app.CurrentTrialPanel);
            app.Lamp_2 = uilamp(app.CurrentTrialPanel);
            app.AbortButton = uibutton(app.CurrentTrialPanel, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Visible = 'off';
            app.AbortButton.Text = 'Abort';
            app.PrintCSVButton_4 = uibutton(app.NovelObjectTab, 'push');
            app.PrintCSVButton_4.ButtonPushedFcn = createCallbackFcn(app, @PrintCSVButton_4Pushed, true);
            app.PrintCSVButton_4.Text = 'Print CSV';
            app.JSETab = uitab(app.TabGroup);
            app.JSETab.Title = 'JSE';
            app.TrialInformationPanel_3 = uipanel(app.JSETab);
            app.TrialInformationPanel_3.Title = 'Trial Information';
            app.DateTextArea_5Label = uilabel(app.TrialInformationPanel_3);
            app.DateTextArea_5Label.Text = 'Date';
            app.DateTextArea_5 = uitextarea(app.TrialInformationPanel_3);
            app.DateTextArea_5.ValueChangedFcn = createCallbackFcn(app, @DateTextArea_5ValueChanged, true);
            app.AnimalTextArea_6Label = uilabel(app.TrialInformationPanel_3);
            app.AnimalTextArea_6Label.Text = 'Animal #';
            app.AnimalTextArea_6 = uitextarea(app.TrialInformationPanel_3);
            app.AnimalTextArea_6.ValueChangedFcn = createCallbackFcn(app, @AnimalTextArea_6ValueChanged, true);
            app.SubmitButton_13 = uibutton(app.TrialInformationPanel_3, 'push');
            app.SubmitButton_13.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_13Pushed, true);
            app.SubmitButton_13.Text = 'Submit';
            app.CurrentTrialPanel_3 = uipanel(app.JSETab);
            app.CurrentTrialPanel_3.Title = 'Current Trial';
            app.AnimalLabel_6 = uilabel(app.CurrentTrialPanel_3);
            app.AnimalLabel_6.Text = 'Animal #:';
            app.NULLLabel_9 = uilabel(app.CurrentTrialPanel_3);
            app.NULLLabel_9.Text = 'NULL';
            app.RecordingLamp_3Label = uilabel(app.CurrentTrialPanel_3);
            app.RecordingLamp_3Label.Text = 'Recording: ';
            app.RecordingLamp_3 = uilamp(app.CurrentTrialPanel_3);
            app.BegintrialButton_3 = uibutton(app.CurrentTrialPanel_3, 'push');
            app.BegintrialButton_3.ButtonPushedFcn = createCallbackFcn(app, @BegintrialButton_3Pushed, true);
            app.BegintrialButton_3.Text = 'Begin trial';
            app.TimeElapsedGaugeLabel = uilabel(app.CurrentTrialPanel_3);
            app.TimeElapsedGaugeLabel.Text = 'Time Elapsed';
            app.TimeElapsedGauge = uigauge(app.CurrentTrialPanel_3, 'linear');
            app.TimeElapsedGauge.Limits = [0 5];
            app.TimeElapsedGauge.MajorTicks = [0 1 2 3 4 5];
            app.TimeElapsedGauge.MinorTicks = [];
            app.ExplorationLabel = uilabel(app.CurrentTrialPanel_3);
            app.ExplorationLabel.Text = 'Exploration:';
            app.NULLLabel_10 = uilabel(app.CurrentTrialPanel_3);
            app.NULLLabel_10.Text = 'NULL';
            app.Lamp_3 = uilamp(app.CurrentTrialPanel_3);
            app.AbortButton_2 = uibutton(app.CurrentTrialPanel_3, 'push');
            app.AbortButton_2.ButtonPushedFcn = createCallbackFcn(app, @AbortButton_2Pushed, true);
            app.AbortButton_2.Visible = 'off';
            app.AbortButton_2.Text = 'Abort';
            app.UITable_3 = uitable(app.JSETab);
            app.UITable_3.ColumnName = {'Date'; 'Animal #'; 'Exploration (s)'; ''};
            app.UITable_3.RowName = {};
            app.PrintCSVButton_3 = uibutton(app.JSETab, 'push');
            app.PrintCSVButton_3.ButtonPushedFcn = createCallbackFcn(app, @PrintCSVButton_3Pushed, true);
            app.PrintCSVButton_3.Text = 'Print CSV';
            app.EffortTubeTab = uitab(app.TabGroup);
            app.EffortTubeTab.Title = 'Effort Tube';
            app.TimesPanel = uipanel(app.EffortTubeTab);
            app.TimesPanel.Title = 'Times';
            app.AnimalLabel_7 = uilabel(app.TimesPanel);
            app.AnimalLabel_7.Text = 'Animal #:';
            app.NULLLabel_11 = uilabel(app.TimesPanel);
            app.NULLLabel_11.Text = 'NULL';
            app.BottleTouchLabel = uilabel(app.TimesPanel);
            app.BottleTouchLabel.Text = 'Bottle Touch:';
            app.BottleDropLabel = uilabel(app.TimesPanel);
            app.BottleDropLabel.Text = 'Bottle Drop:';
            app.GatePullLabel = uilabel(app.TimesPanel);
            app.GatePullLabel.Text = 'Gate Pull:';
            app.mEditField_4Label = uilabel(app.TimesPanel);
            app.mEditField_4Label.Text = 'm';
            app.mEditField_4 = uieditfield(app.TimesPanel, 'numeric');
            app.mEditField_4.ValueChangedFcn = createCallbackFcn(app, @mEditField_4ValueChanged, true);
            app.sEditField_4Label = uilabel(app.TimesPanel);
            app.sEditField_4Label.Text = 's';
            app.sEditField_4 = uieditfield(app.TimesPanel, 'numeric');
            app.sEditField_4.ValueChangedFcn = createCallbackFcn(app, @sEditField_4ValueChanged, true);
            app.mEditField_5Label = uilabel(app.TimesPanel);
            app.mEditField_5Label.Text = 'm';
            app.mEditField_5 = uieditfield(app.TimesPanel, 'numeric');
            app.mEditField_5.ValueChangedFcn = createCallbackFcn(app, @mEditField_5ValueChanged, true);
            app.sEditField_5Label = uilabel(app.TimesPanel);
            app.sEditField_5Label.Text = 's';
            app.sEditField_5 = uieditfield(app.TimesPanel, 'numeric');
            app.sEditField_5.ValueChangedFcn = createCallbackFcn(app, @sEditField_5ValueChanged, true);
            app.mEditField_6Label = uilabel(app.TimesPanel);
            app.mEditField_6Label.Text = 'm';
            app.mEditField_6 = uieditfield(app.TimesPanel, 'numeric');
            app.mEditField_6.ValueChangedFcn = createCallbackFcn(app, @mEditField_6ValueChanged, true);
            app.sEditField_6Label = uilabel(app.TimesPanel);
            app.sEditField_6Label.Text = 's';
            app.sEditField_6 = uieditfield(app.TimesPanel, 'numeric');
            app.sEditField_6.ValueChangedFcn = createCallbackFcn(app, @sEditField_6ValueChanged, true);
            app.WeightorTrialLabel = uilabel(app.TimesPanel);
            app.WeightorTrialLabel.Text = 'Weight or Trial #:';
            app.gLabel = uilabel(app.TimesPanel);
            app.gLabel.Text = 'g / #';
            app.gEditField = uieditfield(app.TimesPanel, 'numeric');
            app.gEditField.ValueChangedFcn = createCallbackFcn(app, @gEditFieldValueChanged, true);
            app.SubmitButton_11 = uibutton(app.TimesPanel, 'push');
            app.SubmitButton_11.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_11Pushed, true);
            app.SubmitButton_11.Text = 'Submit';
            app.UITable_4 = uitable(app.EffortTubeTab);
            app.UITable_4.ColumnName = {'Date'; 'Animal #'; 'Weight or Trial #'; 'Push Time (s)'};
            app.UITable_4.RowName = {};
            app.TrialInformationPanel_5 = uipanel(app.EffortTubeTab);
            app.TrialInformationPanel_5.Title = 'Trial Information';
            app.DateTextArea_3Label = uilabel(app.TrialInformationPanel_5);
            app.DateTextArea_3Label.Text = 'Date';
            app.DateTextArea_3 = uitextarea(app.TrialInformationPanel_5);
            app.DateTextArea_3.ValueChangedFcn = createCallbackFcn(app, @DateTextArea_3ValueChanged, true);
            app.AnimalTextArea_4Label = uilabel(app.TrialInformationPanel_5);
            app.AnimalTextArea_4Label.Text = 'Animal #';
            app.AnimalTextArea_4 = uitextarea(app.TrialInformationPanel_5);
            app.AnimalTextArea_4.ValueChangedFcn = createCallbackFcn(app, @AnimalTextArea_4ValueChanged, true);
            app.SubmitButton_10 = uibutton(app.TrialInformationPanel_5, 'push');
            app.SubmitButton_10.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_10Pushed, true);
            app.SubmitButton_10.Text = 'Submit';
            app.PrintCSVButton_5 = uibutton(app.EffortTubeTab, 'push');
            app.PrintCSVButton_5.ButtonPushedFcn = createCallbackFcn(app, @PrintCSVButton_5Pushed, true);
            app.PrintCSVButton_5.Text = 'Print CSV';
            app.SocialTubeTab = uitab(app.TabGroup);
            app.SocialTubeTab.Title = 'Social Tube';
            app.TrialInformationPanel_2 = uipanel(app.SocialTubeTab);
            app.TrialInformationPanel_2.Title = 'Trial Information';
            app.DayTextAreaLabel = uilabel(app.TrialInformationPanel_2);
            app.DayTextAreaLabel.Text = 'Day';
            app.DayTextArea = uitextarea(app.TrialInformationPanel_2);
            app.DayTextArea.ValueChangedFcn = createCallbackFcn(app, @DayTextAreaValueChanged, true);
            app.TrialTextAreaLabel = uilabel(app.TrialInformationPanel_2);
            app.TrialTextAreaLabel.Text = 'Trial #';
            app.TrialTextArea = uitextarea(app.TrialInformationPanel_2);
            app.TrialTextArea.ValueChangedFcn = createCallbackFcn(app, @TrialTextAreaValueChanged, true);
            app.CohortTextAreaLabel = uilabel(app.TrialInformationPanel_2);
            app.CohortTextAreaLabel.Text = 'Cohort';
            app.CohortTextArea = uitextarea(app.TrialInformationPanel_2);
            app.CohortTextArea.ValueChangedFcn = createCallbackFcn(app, @CohortTextAreaValueChanged, true);
            app.AnimalTextArea_7Label = uilabel(app.TrialInformationPanel_2);
            app.AnimalTextArea_7Label.Text = 'Animal #';
            app.AnimalTextArea_7 = uitextarea(app.TrialInformationPanel_2);
            app.AnimalTextArea_7.ValueChangedFcn = createCallbackFcn(app, @AnimalTextArea_7ValueChanged, true);
            app.SubmitButton_14 = uibutton(app.TrialInformationPanel_2, 'push');
            app.SubmitButton_14.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_14Pushed, true);
            app.SubmitButton_14.Text = 'Submit';
            app.CurrentTrialPanel_2 = uipanel(app.SocialTubeTab);
            app.CurrentTrialPanel_2.Title = 'Current Trial';
            app.CohortLabel = uilabel(app.CurrentTrialPanel_2);
            app.CohortLabel.Text = 'Cohort:';
            app.AnimalLabel_5 = uilabel(app.CurrentTrialPanel_2);
            app.AnimalLabel_5.Text = 'Animal #:';
            app.TrialLabel_2 = uilabel(app.CurrentTrialPanel_2);
            app.TrialLabel_2.Text = 'Trial:';
            app.PushesLabel = uilabel(app.CurrentTrialPanel_2);
            app.PushesLabel.Text = 'Pushes:';
            app.NULLLabel = uilabel(app.CurrentTrialPanel_2);
            app.NULLLabel.Text = 'NULL';
            app.NULLLabel_2 = uilabel(app.CurrentTrialPanel_2);
            app.NULLLabel_2.Text = 'NULL';
            app.NULLLabel_3 = uilabel(app.CurrentTrialPanel_2);
            app.NULLLabel_3.Text = 'NULL';
            app.NULLLabel_4 = uilabel(app.CurrentTrialPanel_2);
            app.NULLLabel_4.Text = 'NULL';
            app.RecordingLamp_2Label = uilabel(app.CurrentTrialPanel_2);
            app.RecordingLamp_2Label.Text = 'Recording: ';
            app.RecordingLamp_2 = uilamp(app.CurrentTrialPanel_2);
            app.TimeElapsedGauge_2Label = uilabel(app.CurrentTrialPanel_2);
            app.TimeElapsedGauge_2Label.Text = 'Time Elapsed';
            app.TimeElapsedGauge_2 = uigauge(app.CurrentTrialPanel_2, 'linear');
            app.TimeElapsedGauge_2.Limits = [0 2];
            app.TimeElapsedGauge_2.MajorTicks = [0 1 2];
            app.TimeElapsedGauge_2.MinorTicks = [];
            app.BegintrialButton_4 = uibutton(app.CurrentTrialPanel_2, 'push');
            app.BegintrialButton_4.ButtonPushedFcn = createCallbackFcn(app, @BegintrialButton_4Pushed, true);
            app.BegintrialButton_4.Text = 'Begin trial';
            app.AbortButton_3 = uibutton(app.CurrentTrialPanel_2, 'push');
            app.AbortButton_3.ButtonPushedFcn = createCallbackFcn(app, @AbortButton_3Pushed, true);
            app.AbortButton_3.Visible = 'off';
            app.AbortButton_3.Text = 'Abort';
            app.UITable_2 = uitable(app.SocialTubeTab);
            app.UITable_2.ColumnName = {'Day'; 'Cohort'; 'Animal #'; 'Trial #'};
            app.UITable_2.RowName = {};
            app.PrintCSVButton_6 = uibutton(app.SocialTubeTab, 'push');
            app.PrintCSVButton_6.ButtonPushedFcn = createCallbackFcn(app, @PrintCSVButton_6Pushed, true);
            app.PrintCSVButton_6.Text = 'Print CSV';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.NovelObjectTrialTimeLabel = uilabel(app.SettingsTab);
            app.NovelObjectTrialTimeLabel.Text = 'Novel Object Trial Time:';
            app.SubmitButton_15 = uibutton(app.SettingsTab, 'push');
            app.SubmitButton_15.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_15Pushed, true);
            app.SubmitButton_15.Text = 'Submit';
            app.JSETrialTimeLabel = uilabel(app.SettingsTab);
            app.JSETrialTimeLabel.Text = 'JSE Trial Time:';
            app.SubmitButton_16 = uibutton(app.SettingsTab, 'push');
            app.SubmitButton_16.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_16Pushed, true);
            app.SubmitButton_16.Text = 'Submit';
            app.SocialTubeTrialTimeLabel = uilabel(app.SettingsTab);
            app.SocialTubeTrialTimeLabel.Text = 'Social Tube Trial Time:';
            app.SubmitButton_17 = uibutton(app.SettingsTab, 'push');
            app.SubmitButton_17.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_17Pushed, true);
            app.SubmitButton_17.Text = 'Submit';
            app.sEditField_7Label = uilabel(app.SettingsTab);
            app.sEditField_7Label.Text = 's';
            app.sEditField_7 = uieditfield(app.SettingsTab, 'numeric');
            app.sEditField_7.ValueChangedFcn = createCallbackFcn(app, @sEditField_7ValueChanged2, true);
            app.sEditField_9Label = uilabel(app.SettingsTab);
            app.sEditField_9Label.Text = 's';
            app.sEditField_9 = uieditfield(app.SettingsTab, 'numeric');
            app.sEditField_9.ValueChangedFcn = createCallbackFcn(app, @sEditField_9ValueChanged, true);
            app.sEditField_8Label = uilabel(app.SettingsTab);
            app.sEditField_8Label.Text = 's';
            app.sEditField_8 = uieditfield(app.SettingsTab, 'numeric');
            app.sEditField_8.ValueChangedFcn = createCallbackFcn(app, @sEditField_8ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScoreApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end